classdef FittingOptionsDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FittingParametersUIFigure     matlab.ui.Figure
        UITable                       matlab.ui.control.Table
        SkewnessStartingValue_Band3   matlab.ui.control.EditField
        ShapeParameterLabel_12        matlab.ui.control.Label
        AmplitudeStartingValue_Band3  matlab.ui.control.EditField
        ShapeParameterLabel_11        matlab.ui.control.Label
        ShapeParameterStartingValue_Band3  matlab.ui.control.EditField
        ShapeParameterLabel_10        matlab.ui.control.Label
        PeakLocationStartingValue_Band3  matlab.ui.control.EditField
        PeakLocationEditFieldLabel_4  matlab.ui.control.Label
        UpdateButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        GelBoxApp % Description
        n
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, caller)
            app.GelBoxApp = caller;
            app.n = str2num(caller.BoxSelectionDropDown.Value);
            t = struct2table(caller.gel_data.par_est(app.n));
            app.UITable.Data = t;

        end

        % Button pushed function: UpdateButton
        function UpdateButtonPushed(app, event)

            UpdateFittingOptions(app.GelBoxApp);
            
            delete(app)
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            new_param = table2struct(app.UITable.Data);
%             app.GelBoxApp.gel_data.par_est(app.n) = [];
            app.GelBoxApp.gel_data.par_est(app.n).band_no = app.UITable.Data.band_no;
            app.GelBoxApp.gel_data.par_est(app.n).peak_location = app.UITable.Data.peak_location;
            app.GelBoxApp.gel_data.par_est(app.n).shape_parameter = app.UITable.Data.shape_parameter;
            app.GelBoxApp.gel_data.par_est(app.n).amplitude = app.UITable.Data.amplitude;
            app.GelBoxApp.gel_data.par_est(app.n).skew_parameter = app.UITable.Data.skew_parameter;
            app.GelBoxApp.gel_data.par_update(app.n) = 1; 

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FittingParametersUIFigure = uifigure('Visible', 'off');
            app.FittingParametersUIFigure.Name = 'Fitting Parameters';
            app.UpdateButton = uibutton(app.FittingParametersUIFigure, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateButtonPushed, true);
            app.UpdateButton.Text = 'Update';
            app.PeakLocationEditFieldLabel_4 = uilabel(app.FittingParametersUIFigure);
            app.PeakLocationEditFieldLabel_4.Text = 'Peak Location';
            app.PeakLocationStartingValue_Band3 = uieditfield(app.FittingParametersUIFigure, 'text');
            app.ShapeParameterLabel_10 = uilabel(app.FittingParametersUIFigure);
            app.ShapeParameterLabel_10.Text = 'Shape Parameter';
            app.ShapeParameterStartingValue_Band3 = uieditfield(app.FittingParametersUIFigure, 'text');
            app.ShapeParameterLabel_11 = uilabel(app.FittingParametersUIFigure);
            app.ShapeParameterLabel_11.Text = 'Amplitude';
            app.AmplitudeStartingValue_Band3 = uieditfield(app.FittingParametersUIFigure, 'text');
            app.ShapeParameterLabel_12 = uilabel(app.FittingParametersUIFigure);
            app.ShapeParameterLabel_12.Text = 'Skewness Parameter';
            app.SkewnessStartingValue_Band3 = uieditfield(app.FittingParametersUIFigure, 'text');
            app.UITable = uitable(app.FittingParametersUIFigure);
            app.UITable.ColumnName = {'Band No'; 'Peak Location'; 'Shape Parameter'; 'Amplitude'; 'Skewness Parameter'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true true true true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            % Show the figure after all components are created
            app.FittingParametersUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FittingOptionsDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FittingParametersUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FittingParametersUIFigure)
        end
    end
end